classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResizeButton            matlab.ui.control.Button
        SelectImageButton       matlab.ui.control.Button
        scaleEditFieldLabel     matlab.ui.control.Label
        scaleEditField          matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        RotateButton            matlab.ui.control.Button
        degreeEditFieldLabel    matlab.ui.control.Label
        degreeEditField         matlab.ui.control.NumericEditField
        ImagesizeLabel          matlab.ui.control.Label
        WidthEditFieldLabel     matlab.ui.control.Label
        WidthEditField          matlab.ui.control.NumericEditField
        HeightEditField_2Label  matlab.ui.control.Label
        HeightEditField         matlab.ui.control.NumericEditField
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        CropButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           app.Image.ImageSource = ImageFile;
           
           [rows, cols, ~] = size(ImageFile);
           imageSizeText = sprintf('Image size: %d x %d', rows, cols);
           app.ImagesizeLabel.Text = imageSizeText;
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            scale = app.scaleEditField.Value;
            image = app.Image.ImageSource;
            if isempty(image)
                errordlg('Please, select image','Image not found');
                return;
            end
            resized_image = resize_image(image, scale);
            [rows, cols, ~] = size(resized_image);
            imshow(resized_image);
            title(sprintf('Resised image. Image size: %d x %d', rows, cols));
            
            disp(ssim(image, resized_image));
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            image = app.Image.ImageSource;
            degree = app.degreeEditField.Value;
            if isempty(image)
                errordlg('Please, select image','Image not found');
                return;
            end
            rotated_image = rotate_image(image,degree);
            imshow(rotated_image);
            title('Rotated image')
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            image = app.Image.ImageSource;
            if isempty(image)
                errordlg('Please, select image','Image not found');
                return;
            end
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            height = app.HeightEditField.Value;
            width = app.WidthEditField.Value;
            try
                cropped_image = crop_image(image, x, y, width, height);
                imshow(cropped_image);
                title('Cropped image')
            catch ME
                errordlg(ME.message, 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.scaleEditFieldLabel = uilabel(app.UIFigure);
            app.scaleEditFieldLabel.Text = 'scale ';
            app.scaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.scaleEditField.Limits = [0.1 Inf];
            app.scaleEditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.degreeEditFieldLabel = uilabel(app.UIFigure);
            app.degreeEditFieldLabel.Text = 'degree';
            app.degreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.degreeEditField.Value = 45;
            app.ImagesizeLabel = uilabel(app.UIFigure);
            app.ImagesizeLabel.Text = 'Image size:';
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.Value = 400;
            app.HeightEditField_2Label = uilabel(app.UIFigure);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.Limits = [1 Inf];
            app.HeightEditField.Value = 200;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [0 Inf];
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Limits = [0 Inf];
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end